classdef sems_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SalePriceSlider                matlab.ui.control.Slider
        SalePriceSliderLabel           matlab.ui.control.Label
        PurchasePriceSlider            matlab.ui.control.Slider
        PurchasePriceSliderLabel       matlab.ui.control.Label
        extPowerCloudSwitch            matlab.ui.control.ToggleSwitch
        extPowerCloudSwitchLabel       matlab.ui.control.Label
        FeedInTariffSlider             matlab.ui.control.Slider
        FeedInTariffSliderLabel        matlab.ui.control.Label
        GridRateSlider                 matlab.ui.control.Slider
        GridRateSliderLabel            matlab.ui.control.Label
        DisplayOutputTextArea          matlab.ui.control.TextArea
        CLElectricityDemandinkWEditField  matlab.ui.control.NumericEditField
        CLElectricityDemandinkWhLabel  matlab.ui.control.Label
        NCLElectricityDemandinkWEditField  matlab.ui.control.NumericEditField
        NCLElectricityDemandinkWEditFieldLabel  matlab.ui.control.Label
        ElectricGridSwitch             matlab.ui.control.ToggleSwitch
        ElectricGridSwitchLabel        matlab.ui.control.Label
        BatterySOCGauge                matlab.ui.control.LinearGauge
        BatterySOCGaugeLabel           matlab.ui.control.Label
        CriticalLoadsLamp              matlab.ui.control.Lamp
        CriticalLoadsLampLabel         matlab.ui.control.Label
        NonCriticalLoadsLamp           matlab.ui.control.Lamp
        NonCriticalLoadsLabel          matlab.ui.control.Label
        HydroPowerkWKnob               matlab.ui.control.Knob
        HydroPowerLabel                matlab.ui.control.Label
        EnergyHarvestingDeviceskWKnob  matlab.ui.control.Knob
        EnergyHarvestingDevicesLabel   matlab.ui.control.Label
        WindPowerkWKnob                matlab.ui.control.Knob
        WindPowerLabel                 matlab.ui.control.Label
        SolarPowerkWKnob               matlab.ui.control.Knob
        SolarPowerLabel                matlab.ui.control.Label
        FuelLevelCriticalLamp          matlab.ui.control.Lamp
        FuelLevelCriticalLampLabel     matlab.ui.control.Label
        FuelLevelGauge                 matlab.ui.control.SemicircularGauge
        FuelLevelGaugeLabel            matlab.ui.control.Label
        GasRefillButton                matlab.ui.control.Button
    end


    properties (Access = public)
        SEMS % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SEMS = SEMS_StateFlowAlgorithm(extPowerCloudSaleState = 0, gridStatus = 0, fuelLevel = 100, extPowerCloudSaleRate = 0, extPowerCloudPurchaseRate = 0, feedInTariff = 0, gridSaleRate = 0, SP = 0, WP = 0, HP = 0, EHD = 0, batterySOC = 0, NCL = 0, EPC = 0, Grid = 0, CL = 0, app = app);

        end

        % Value changed function: ElectricGridSwitch
        function ElectricGridSwitchValueChanged(app, event)
            value = app.ElectricGridSwitch.Value;
            switch value
                case 'GridOutage'
                    GridOutage(app.SEMS);
                case 'GridOn'
                    GridOn(app.SEMS);
            end
        end

        % Value changed function: SolarPowerkWKnob
        function SolarPowerkWKnobValueChanged(app, event)
            app.SEMS.SP = app.SolarPowerkWKnob.Value;
            app.BatterySOCGauge.Value = app.SEMS.batterySOC;
        end

        % Value changed function: WindPowerkWKnob
        function WindPowerkWKnobValueChanged(app, event)
            app.SEMS.WP = app.WindPowerkWKnob.Value;
            app.BatterySOCGauge.Value = app.SEMS.batterySOC;
        end

        % Value changed function: EnergyHarvestingDeviceskWKnob
        function EnergyHarvestingDeviceskWKnobValueChanged(app, event)
            app.SEMS.EHD = app.EnergyHarvestingDeviceskWKnob.Value;
            app.BatterySOCGauge.Value = app.SEMS.batterySOC;
        end

        % Value changed function: HydroPowerkWKnob
        function HydroPowerkWKnobValueChanged(app, event)
            app.SEMS.HP = app.HydroPowerkWKnob.Value;
            app.BatterySOCGauge.Value = app.SEMS.batterySOC;
        end

        % Button pushed function: GasRefillButton
        function GasRefillButtonPushed(app, event)
            GasRefill(app.SEMS);
            app.SEMS.fuelLevel = 100;
        end

        % Value changed function: GridRateSlider
        function GridRateSliderValueChanged(app, event)
            app.SEMS.gridSaleRate = app.GridRateSlider.Value;            
        end

        % Value changed function: NCLElectricityDemandinkWEditField
        function NCLElectricityDemandinkWEditFieldValueChanged(app, event)
            app.SEMS.NCL = app.NCLElectricityDemandinkWEditField.Value;
            app.BatterySOCGauge.Value = app.SEMS.batterySOC;
        end

        % Value changed function: CLElectricityDemandinkWEditField
        function CLElectricityDemandinkWEditFieldValueChanged(app, event)
            app.SEMS.CL = app.CLElectricityDemandinkWEditField.Value;
            app.BatterySOCGauge.Value = app.SEMS.batterySOC;
        end

        % Value changed function: extPowerCloudSwitch
        function extPowerCloudSwitchValueChanged(app, event)
            value = app.extPowerCloudSwitch.Value;
            switch value
                case 'ExtPowerCloudOn'            
                    ExtPowerCloudOn(app.SEMS);
                case 'ExtPowerCloudOff'
                    ExtPowerCloudOff(app.SEMS);
            end
        end

        % Value changed function: FeedInTariffSlider
        function FeedInTariffSliderValueChanged(app, event)
            app.SEMS.feedInTariff = app.FeedInTariffSlider.Value;
            
        end

        % Value changed function: PurchasePriceSlider
        function PurchasePriceSliderValueChanged(app, event)
            app.SEMS.extPowerCloudPurchaseRate = app.PurchasePriceSlider.Value;
            
        end

        % Value changed function: SalePriceSlider
        function SalePriceSliderValueChanged(app, event)
            app.SEMS.extPowerCloudSaleRate = app.SalePriceSlider.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.SEMS);
            delete(app);            
        end

        % Value changed function: DisplayOutputTextArea
        function DisplayOutputTextAreaValueChanged(app, event)
            value = app.DisplayOutputTextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GasRefillButton = uibutton(app.UIFigure, 'push');
            app.GasRefillButton.ButtonPushedFcn = createCallbackFcn(app, @GasRefillButtonPushed, true);
            app.GasRefillButton.Text = 'Gas Refill';
            app.FuelLevelGaugeLabel = uilabel(app.UIFigure);
            app.FuelLevelGaugeLabel.Text = 'Fuel Level';
            app.FuelLevelGauge = uigauge(app.UIFigure, 'semicircular');
            app.FuelLevelCriticalLampLabel = uilabel(app.UIFigure);
            app.FuelLevelCriticalLampLabel.Text = 'Fuel Level Critical';
            app.FuelLevelCriticalLamp = uilamp(app.UIFigure);
            app.SolarPowerLabel = uilabel(app.UIFigure);
            app.SolarPowerLabel.Text = 'Solar Power kW';
            app.SolarPowerkWKnob = uiknob(app.UIFigure, 'continuous');
            app.SolarPowerkWKnob.ValueChangedFcn = createCallbackFcn(app, @SolarPowerkWKnobValueChanged, true);
            app.WindPowerLabel = uilabel(app.UIFigure);
            app.WindPowerLabel.Text = 'Wind Power kW';
            app.WindPowerkWKnob = uiknob(app.UIFigure, 'continuous');
            app.WindPowerkWKnob.ValueChangedFcn = createCallbackFcn(app, @WindPowerkWKnobValueChanged, true);
            app.EnergyHarvestingDevicesLabel = uilabel(app.UIFigure);
            app.EnergyHarvestingDevicesLabel.Text = 'Energy Harvesting Devices kW ';
            app.EnergyHarvestingDeviceskWKnob = uiknob(app.UIFigure, 'continuous');
            app.EnergyHarvestingDeviceskWKnob.ValueChangedFcn = createCallbackFcn(app, @EnergyHarvestingDeviceskWKnobValueChanged, true);
            app.HydroPowerLabel = uilabel(app.UIFigure);
            app.HydroPowerLabel.Text = 'Hydro Power kW';
            app.HydroPowerkWKnob = uiknob(app.UIFigure, 'continuous');
            app.HydroPowerkWKnob.ValueChangedFcn = createCallbackFcn(app, @HydroPowerkWKnobValueChanged, true);
            app.NonCriticalLoadsLabel = uilabel(app.UIFigure);
            app.NonCriticalLoadsLabel.Text = 'Non Critical Loads';
            app.NonCriticalLoadsLamp = uilamp(app.UIFigure);
            app.CriticalLoadsLampLabel = uilabel(app.UIFigure);
            app.CriticalLoadsLampLabel.Text = 'Critical Loads';
            app.CriticalLoadsLamp = uilamp(app.UIFigure);
            app.BatterySOCGaugeLabel = uilabel(app.UIFigure);
            app.BatterySOCGaugeLabel.Text = 'Battery SOC';
            app.BatterySOCGauge = uigauge(app.UIFigure, 'linear');
            app.ElectricGridSwitchLabel = uilabel(app.UIFigure);
            app.ElectricGridSwitchLabel.Text = 'Electric Grid';
            app.ElectricGridSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ElectricGridSwitch.Items = {'GridOutage', 'GridOn'};
            app.ElectricGridSwitch.ValueChangedFcn = createCallbackFcn(app, @ElectricGridSwitchValueChanged, true);
            app.ElectricGridSwitch.Value = 'GridOutage';
            app.NCLElectricityDemandinkWEditFieldLabel = uilabel(app.UIFigure);
            app.NCLElectricityDemandinkWEditFieldLabel.Text = 'NCL - Electricity Demand in kW';
            app.NCLElectricityDemandinkWEditField = uieditfield(app.UIFigure, 'numeric');
            app.NCLElectricityDemandinkWEditField.ValueChangedFcn = createCallbackFcn(app, @NCLElectricityDemandinkWEditFieldValueChanged, true);
            app.NCLElectricityDemandinkWEditField.Value = 10;
            app.CLElectricityDemandinkWhLabel = uilabel(app.UIFigure);
            app.CLElectricityDemandinkWhLabel.Text = 'CL - Electricity Demand in kW';
            app.CLElectricityDemandinkWEditField = uieditfield(app.UIFigure, 'numeric');
            app.CLElectricityDemandinkWEditField.ValueChangedFcn = createCallbackFcn(app, @CLElectricityDemandinkWEditFieldValueChanged, true);
            app.CLElectricityDemandinkWEditField.Value = 1;
            app.DisplayOutputTextArea = uitextarea(app.UIFigure);
            app.DisplayOutputTextArea.ValueChangedFcn = createCallbackFcn(app, @DisplayOutputTextAreaValueChanged, true);
            app.GridRateSliderLabel = uilabel(app.UIFigure);
            app.GridRateSliderLabel.Text = 'Grid Rate';
            app.GridRateSlider = uislider(app.UIFigure);
            app.GridRateSlider.Limits = [1 50];
            app.GridRateSlider.MajorTicks = [1 50];
            app.GridRateSlider.ValueChangedFcn = createCallbackFcn(app, @GridRateSliderValueChanged, true);
            app.GridRateSlider.Value = 1;
            app.FeedInTariffSliderLabel = uilabel(app.UIFigure);
            app.FeedInTariffSliderLabel.Text = 'Feed-In Tariff';
            app.FeedInTariffSlider = uislider(app.UIFigure);
            app.FeedInTariffSlider.Limits = [1 50];
            app.FeedInTariffSlider.ValueChangedFcn = createCallbackFcn(app, @FeedInTariffSliderValueChanged, true);
            app.FeedInTariffSlider.Value = 1;
            app.extPowerCloudSwitchLabel = uilabel(app.UIFigure);
            app.extPowerCloudSwitchLabel.Text = 'ext Power Cloud';
            app.extPowerCloudSwitch = uiswitch(app.UIFigure, 'toggle');
            app.extPowerCloudSwitch.Items = {'ExtPowerCloudOff', 'ExtPowerCloudOn'};
            app.extPowerCloudSwitch.ValueChangedFcn = createCallbackFcn(app, @extPowerCloudSwitchValueChanged, true);
            app.extPowerCloudSwitch.Value = 'ExtPowerCloudOff';
            app.PurchasePriceSliderLabel = uilabel(app.UIFigure);
            app.PurchasePriceSliderLabel.Text = 'Purchase Price';
            app.PurchasePriceSlider = uislider(app.UIFigure);
            app.PurchasePriceSlider.Limits = [1 50];
            app.PurchasePriceSlider.ValueChangedFcn = createCallbackFcn(app, @PurchasePriceSliderValueChanged, true);
            app.PurchasePriceSlider.Value = 1;
            app.SalePriceSliderLabel = uilabel(app.UIFigure);
            app.SalePriceSliderLabel.Text = 'Sale Price';
            app.SalePriceSlider = uislider(app.UIFigure);
            app.SalePriceSlider.Limits = [1 50];
            app.SalePriceSlider.MajorTicks = [1 50];
            app.SalePriceSlider.ValueChangedFcn = createCallbackFcn(app, @SalePriceSliderValueChanged, true);
            app.SalePriceSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sems_ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end